classdef SWDapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WybrkryteriwButton           matlab.ui.control.Button
        WektorWagEditField           matlab.ui.control.EditField
        WektorWagEditFieldLabel      matlab.ui.control.Label
        StwrzrankingButton           matlab.ui.control.Button
        WczytajdanezplikuButton_2    matlab.ui.control.Button
        wybierzmetodeDropDown        matlab.ui.control.DropDown
        wybierzmetodeDropDownLabel   matlab.ui.control.Label
        RankingLabel                 matlab.ui.control.Label
        KlasyLabel                   matlab.ui.control.Label
        AlternatywyzkryteriamiLabel  matlab.ui.control.Label
        UITable3                     matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        daneA = []; % Description
        daneK = [];
        wektorWag = [];
        alternatywy = [];
        dane1 = [];
        dane2 = [];
        options = [];
        selection = [];


    end
    
    methods (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function result = ReferenceSetMethod(app, U)
        A0 = min(U);
        A1 = max(U);
        A2 = median(U);
        A3 = A1 + 0.1 * (A1 - A0);
            
        figure;
        scatter3(U(:, 1), U(:, 2), U(:, 3), 'filled');
        title('Zbiór U');
        
        paretoSet = app.calculateParetoSet(U);
        
        hold on;
        scatter3(U(paretoSet, 1), U(paretoSet, 2), U(paretoSet, 3), 'r', 'filled');
        legend('Zbiór U', 'Zbiór Pareto');
        
        
        %A0 = A0(~app.dominates(A0, U));
        %A1 = A1(~app.dominates(A1, U));
        %A2 = A2(~app.dominates(A2, U));
        %A3 = A3(~app.dominates(A3, U));
        
        distances_A0 = sqrt(sum((U - A0).^2, 2));
        distances_A1 = sqrt(sum((U - A1).^2, 2));
        
        ranking = distances_A0 - distances_A1;
        
        [~, idx] = min(ranking);
        bestPoint = U(idx, :)


        % Wizualizacja metryk
        figure;
        bar([distances_A0, distances_A1]);
        xlabel('Alternatywa');
        ylabel('Odległość');
        legend('Od A0', 'Od A1');
        title('Odległości od punktów odniesienia');


        [liczbaAlternatyw,~] = size(U);
        result_ranking = cell(liczbaAlternatyw,2);
        for i =1:liczbaAlternatyw
            result_ranking{i,1} = app.alternatywy(i); 
            result_ranking{i,2} = ranking(i);
        end
        ranking_sorted = flip(sortrows(result_ranking, 2), 1);
        ranking_table = cell2table(ranking_sorted);
        result = ranking_table;

        end

        function paretoSet = calculateParetoSet(app, U)
            paretoSet = true(1, size(U, 1));
            [~, numObjectives] = size(U);
        
            for i = 1:size(U, 1)
                for j = 1:size(U, 1)
                    if i ~= j
                        dominates_i = all(U(i, :) <= U(j, :)) && any(U(i, :) < U(j, :));
                        if dominates_i
                            paretoSet(i) = false;
                            break;
                        end
                    end
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        function scoring_function = RSM(app, A, SQ, U)
            % Algorytm główny
            
            % Liczba par punktów aspiracji
            n = size(A, 1);
            
            % Liczba punktów status-quo
            m = size(SQ, 1);
            
            % Liczba punktów zbioru U
            p = size(U, 1);
            
            % Inicjalizacja wektora wynikowego
            scoring_function = zeros(p, 1);
            
            % Iteracja po punktach zbioru U
            for i = 1:p
                u = U(i, :);
                
                % Inicjalizacja wektora wynikowego dla każdego punktu U
                scores = zeros(n + m, 1);
                
                % Krok 2: Szukanie punktów aspiracji i status-quo dla punktu u
                for j = 1:n
                    for k = 1:m
                        % Sprawdzenie warunku z kroku 2
                        if A(j, 1) <= u(1) && u(1) <= SQ(k, 1)
                            % Krok 3: Obliczanie funkcji skoringowej
                            scores(j) = app.compute_score(A(j, :), SQ(k, :), u);
                        elseif SQ(k, 1) <= u(1) && u(1) <= A(j, 1)
                            % Krok 3: Obliczanie funkcji skoringowej
                            scores(j) = app.compute_score(SQ(k, :), A(j, :), u);
                        end
                    end
                end
                
                % Krok 4: Obsługa przypadku, gdy u nie jest porównywalne z żadnym elementem
                if all(scores == 0)
                    [~, idx] = min(abs(A(:, 1) - u(1)));
                    scores = app.compute_score(A(idx, :), SQ(idx, :), u);
                end
                
                % Krok 5: Obsługa przypadku, gdy u nie jest porównywalne z żadnym elementem z klas aspiracji i status-quo
                %if all(scores == 0)
                %    scores = extended_method(u);
                %end
                
                % Krok 6: Sumowanie wyników funkcji skoringowej dla punktu u
                scoring_function(i) = sum(scores);
            end
            
            % Sortowanie punktów zbioru U według funkcji skoringowej
            [~, sorted_indices] = sort(scoring_function, 'descend');
            scoring_function = U(sorted_indices, :);
        end


        function score = compute_score(app, aij, aik, u)
            % A - macierz punktów, gdzie A(:, :, 1) to współrzędne x, a A(:, :, 2) to współrzędne y
            % u - punkt zbioru U
        
            % Konstruowanie macierzy punktów dla funkcji skoringowej
            A_pair = cat(3, aij, aik);
        
            % Wywołanie funkcji skoringowej
            scores = app.scoringFunction(A_pair, u);
        
            % Przypisanie wyniku
            score = sum(scores);
        end
        
        
        function scores = scoringFunction(app, A, U)
            % A - macierz punktów, gdzie A(:, :, 1) to współrzędne x, a A(:, :, 2) to współrzędne y
            % U - zbiór punktów, dla których obliczamy wyniki funkcji skoringowej
        
            % Inicjalizacja wyników
            scores = zeros(size(U, 1), 1);
        
            % Pętla po parach punktów a(i,j) i a(i-1,k)
            for i = 2:size(A, 1)
                % Konstruowanie prostej sigma_jk przechodzącej przez punkty a(i,j) i a(i-1,k)
                x1 = A(i, :, 1);
                y1 = A(i, :, 2);
                x2 = A(i-1, :, 1);
                y2 = A(i-1, :, 2);
        
                % Znajdowanie parametru t
                t = (x1 - x2) ./ (x1 - x2 + y1 - y2);
        
                % Obliczanie odległości dla każdego punktu U
                for j = 1:size(U, 1)
                    u = U(j, :);
        
                    % Obliczanie odległości przy użyciu metryki Czebyszewa
                    distances = max(abs(u(1) - x1), abs(u(2) - y1));
                    [~, idx] = min(distances);
        
                    % Przypisywanie wartości parametru t jako wyniku
                    scores(j) = t(idx);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajdanezplikuButton_2
        function WczytajdanezplikuButton_2Pushed(app, event)
            [fileName, filePath] = uigetfile({'*.xls', 'Pliki Excel (*.xls)'; '*.*', 'Wszystkie pliki (*.*)'}, 'Wybierz plik Excel');
            if isequal(fileName, 0)
                return;
            end
            fullFilePath = fullfile(filePath, fileName);
            
            app.dane1 = readtable(fullFilePath,'Sheet', 1);
            app.dane2 = readtable(fullFilePath,'Sheet', 2);
            app.UITable.Data = app.dane1;
            originalColumnNames1 = app.dane1.Properties.VariableDescriptions;
            app.UITable.ColumnName = originalColumnNames1;

            app.UITable2.Data = app.dane2;
            originalColumnNames2 = app.dane2.Properties.VariableDescriptions;
            app.UITable2.ColumnName = originalColumnNames2;
            
            app.daneA = app.dane1{1:end, 3:end};
            app.daneK = app.dane2{1:end, 2:end};
            app.alternatywy = app.dane1{1:end, 2};
        end

        % Button pushed function: StwrzrankingButton
        function StwrzrankingButtonPushed(app, event)
            value = app.wybierzmetodeDropDown.Value;
            if value == "TOPSIS"
                app.wektorWag
                [liczbaAlternatyw,~] = size(app.daneA);
                iloscKryteriow = 3;
                
                [liczbaKlas,~] = size(app.daneK);
                [~,iloscWymiarow] = size(app.daneK);
                app.selection

                % sprawdzenie punktow alternatyw
                alternatywyOK = zeros(liczbaAlternatyw, 1);
                for i =1:liczbaAlternatyw
                    for j = 1:3
                        if app.daneA(i,app.selection(j)) >= app.daneK(1,app.selection(j)) && app.daneA(i,app.selection(j)) <= app.daneK(2,app.selection(j))
                            alternatywyOK(i,1) = i;
                        else alternatywyOK(i,1) = 0;
                        end
                    end 
                end
                
                liczbaAlternatywTemp = 0;
                for i =1:liczbaAlternatyw
                    if alternatywyOK(i,1) == 0
                    else liczbaAlternatywTemp = liczbaAlternatywTemp + 1;
                    end
                end
                
                % uzupelnienie macierzy decyzyjnej
                id = 1;
                macierz_decyzyjna = zeros(liczbaAlternatywTemp,iloscKryteriow);
                for i =1:liczbaAlternatyw
                    if alternatywyOK(i,1) ~= 0 
                        for j = 1:3
                            macierz_decyzyjna(id,j) = app.daneA(i,app.selection(j));
                        end
                        id= id+1;
                    end
                end
                liczbaAlternatyw = liczbaAlternatywTemp;
                
                % proces skalowania
                macierz_skalowana = zeros(liczbaAlternatyw,iloscKryteriow);

                for i =1:liczbaAlternatyw
                    for j = 1:3
                        macierz_skalowana(i,j) = (macierz_decyzyjna(i,j) * app.wektorWag(j))/sqrt(sum((double(macierz_decyzyjna(:,j)).^2)));
                    end 
                end
                
                % wyznaczenie wektora idealnego oraz antyidealnego
                %wektorIdealny = [max(macierz_skalowana(:,1)) min(macierz_skalowana(:,2)) min(macierz_skalowana(:,3))];
                %wektorAntyIdealny = [min(macierz_skalowana(:,1)) max(macierz_skalowana(:,2)) max(macierz_skalowana(:,3))];
                
                wektorIdealny = zeros(1,3);
                wektorAntyIdealny = zeros(1,3);

                for i = 1:3
                    if strcmp(app.options{i}, 'max')
                        wektorIdealny(i) = max(macierz_skalowana(:,i));
                        wektorAntyIdealny(i) = min(macierz_skalowana(:,i));
                    elseif strcmp(app.options{i}, 'min')
                        wektorIdealny(i) = min(macierz_skalowana(:,i));
                        wektorAntyIdealny(i) = max(macierz_skalowana(:,i));
                    end
                end

                % wyznaczenie odleglosci w przestrzeni euklidesowej
                odleglosci = zeros(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    sumaIdealny = 0;
                    sumaAntyIdealny = 0;
                    for j = 1:3
                        sumaIdealny = sumaIdealny+(macierz_skalowana(i,j) - wektorIdealny(j)).^2;
                        sumaAntyIdealny = sumaAntyIdealny+(macierz_skalowana(i,j) - wektorAntyIdealny(j)).^2;
                    end
                 
                    odleglosci(i,1) = sqrt(sumaIdealny);
                    odleglosci(i,2) = sqrt(sumaAntyIdealny);
                end
                
                % uszeregowanie obiektow
                ranking = cell(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    ranking{i,1} = app.alternatywy(i); 
                    ranking{i,2} = odleglosci(i,2)/(odleglosci(i,1)+odleglosci(i,2));
                end
                ranking_sorted = flip(sortrows(ranking, 2), 1);

                % listing wynikow
                alternatywyOK
                macierz_decyzyjna
                app.wektorWag
                macierz_skalowana
                wektorIdealny
                wektorAntyIdealny
                odleglosci
                ranking
                ranking_table = cell2table(ranking_sorted);
                app.UITable3.Data = ranking_table;
                %rysowanie
                figure(1);
                plot3(macierz_decyzyjna(:,1),macierz_decyzyjna(:,2),macierz_decyzyjna(:,3),'+');
                hold on;
                plot3(app.daneK(:,app.selection(1)),app.daneK(:,app.selection(2)),app.daneK(:,app.selection(3)),'o');
                hold off;
            end


            if value == "RSM"
                A = [2699, 3.7, 14, 4;
                     3000, 4, 17, 2];
                SQ = [ 6000, 3.5, 13, 2;
                       5700, 4, 15, 1];
                result = app.RSM(A,SQ,app.daneA);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                result = app.ReferenceSetMethod(app.daneA(1:end, 1:3));
                app.UITable3.Data = result;


            end

        end

        % Value changed function: wybierzmetodeDropDown
        function DropDownValueChanged(app, event)
            selectedOption = app.wybierzmetodeDropDown.Value;
            if strcmp(selectedOption, "TOPSIS")
                app.WektorWagEditField.Visible = 'on';
                app.WybrkryteriwButton.Visible = 'on';
                app.WektorWagEditFieldLabel.Visible = 'on';
            else
                app.WektorWagEditField.Visible = 'off';
                app.WybrkryteriwButton.Visible = 'off';
                app.WektorWagEditFieldLabel.Visible = 'off';
            end
        end

        % Value changed function: WektorWagEditField
        function EditFieldValueChanged(app, event)
            weights = str2num(app.WektorWagEditField.Value);
            disp(weights)
            app.wektorWag = weights;
        end

        % Button pushed function: WybrkryteriwButton
        function WybrkryteriwButtonPushed(app, event)
            columnNames = app.dane1.Properties.VariableNames;
            prompt = 'Wybierz trzy kryteria:';
            [app.selection, ok] = listdlg('ListString', columnNames(3:end), 'PromptString', prompt, 'SelectionMode', 'multiple', 'ListSize', [300, 150]);
            
            if ~ok
                return;
            end
            
            app.options = cell(1, numel(app.selection));
            for i = 1:numel(app.selection)
                prompt = sprintf('Wybierz opcję dla kryterium "%s":', columnNames{app.selection(i) + 2});
                app.options{i} = questdlg(prompt, 'Wybór maksymalizowania/minimalizowania kryterium', 'min', 'max', 'min');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.AlternatywyzkryteriamiLabel = uilabel(app.UIFigure);
            app.AlternatywyzkryteriamiLabel.Text = 'Alternatywy z kryteriami';
            app.KlasyLabel = uilabel(app.UIFigure);
            app.KlasyLabel.Text = 'Klasy';
            app.RankingLabel = uilabel(app.UIFigure);
            app.RankingLabel.Text = 'Ranking';
            app.wybierzmetodeDropDownLabel = uilabel(app.UIFigure);
            app.wybierzmetodeDropDownLabel.Text = 'wybierz metode';
            app.wybierzmetodeDropDown = uidropdown(app.UIFigure);
            app.wybierzmetodeDropDown.Items = {'TOPSIS', 'RSM'};
            app.wybierzmetodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.wybierzmetodeDropDown.Value = 'RSM';
            app.WczytajdanezplikuButton_2 = uibutton(app.UIFigure, 'push');
            app.WczytajdanezplikuButton_2.ButtonPushedFcn = createCallbackFcn(app, @WczytajdanezplikuButton_2Pushed, true);
            app.WczytajdanezplikuButton_2.Text = 'Wczytaj dane z pliku';
            app.StwrzrankingButton = uibutton(app.UIFigure, 'push');
            app.StwrzrankingButton.ButtonPushedFcn = createCallbackFcn(app, @StwrzrankingButtonPushed, true);
            app.StwrzrankingButton.Text = 'Stwórz ranking';
            app.WektorWagEditFieldLabel = uilabel(app.UIFigure);
            app.WektorWagEditFieldLabel.Visible = 'off';
            app.WektorWagEditFieldLabel.Text = 'Wektor Wag';
            app.WektorWagEditField = uieditfield(app.UIFigure, 'text');
            app.WektorWagEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WektorWagEditField.Visible = 'off';
            app.WektorWagEditField.Value = '[0.3 0.4 0.3]';
            app.WybrkryteriwButton = uibutton(app.UIFigure, 'push');
            app.WybrkryteriwButton.ButtonPushedFcn = createCallbackFcn(app, @WybrkryteriwButtonPushed, true);
            app.WybrkryteriwButton.Visible = 'off';
            app.WybrkryteriwButton.Text = 'Wybór kryteriów';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWDapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end